classdef FEM_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout3                   matlab.ui.container.GridLayout
        YieldStrengthEditField        matlab.ui.control.NumericEditField
        YieldStrengthEditFieldLabel   matlab.ui.control.Label
        EditField                     matlab.ui.control.NumericEditField
        SafetyFactorCheckBox          matlab.ui.control.CheckBox
        MaterialDropDown              matlab.ui.control.DropDown
        MaterialDropDownLabel         matlab.ui.control.Label
        CrossSectionalAreaAEditField  matlab.ui.control.NumericEditField
        CrossSectionalAreaAEditFieldLabel  matlab.ui.control.Label
        YoungsModulusEEditField       matlab.ui.control.NumericEditField
        YoungsModulusEEditFieldLabel  matlab.ui.control.Label
        MaterialPropertiesLabel       matlab.ui.control.Label
        ScaleSlider                   matlab.ui.control.Slider
        ScaleSliderLabel              matlab.ui.control.Label
        GridLayout2                   matlab.ui.container.GridLayout
        RemoveButton                  matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        ApplyButton                   matlab.ui.control.Button
        MagnitudeEditField            matlab.ui.control.NumericEditField
        MagnitudeEditFieldLabel       matlab.ui.control.Label
        AxisSwitch                    matlab.ui.control.Switch
        AxisSwitchLabel               matlab.ui.control.Label
        BoundaryConditionSwitch       matlab.ui.control.ToggleSwitch
        BoundaryConditionLabel        matlab.ui.control.Label
        NodeSpinner                   matlab.ui.control.Spinner
        NodeSpinnerLabel              matlab.ui.control.Label
        StyleButtonGroup              matlab.ui.container.ButtonGroup
        Cool                          matlab.ui.control.ToggleButton
        Winter                        matlab.ui.control.ToggleButton
        Bone                          matlab.ui.control.ToggleButton
        Parula                        matlab.ui.control.ToggleButton
        Jet                           matlab.ui.control.ToggleButton
        ImportfromExcelButton         matlab.ui.control.Button
        NodeLabelSwitch               matlab.ui.control.Switch
        NodeLabelSwitchLabel          matlab.ui.control.Label
        ElementListLabel              matlab.ui.control.Label
        EL_Table                      matlab.ui.control.Table
        GenerateElementButton         matlab.ui.control.Button
        ElementLabel                  matlab.ui.control.Label
        Node2                         matlab.ui.control.NumericEditField
        Node2EditFieldLabel           matlab.ui.control.Label
        Node1                         matlab.ui.control.NumericEditField
        Node1EditFieldLabel           matlab.ui.control.Label
        NodeListLabel                 matlab.ui.control.Label
        NL_Table                      matlab.ui.control.Table
        GenerateNodeButton            matlab.ui.control.Button
        NodeLabel                     matlab.ui.control.Label
        Y_coord                       matlab.ui.control.NumericEditField
        YLabel                        matlab.ui.control.Label
        X_coord                       matlab.ui.control.NumericEditField
        XLabel                        matlab.ui.control.Label
        DisplacementAxes              matlab.ui.control.UIAxes
        StressAxes                    matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        NL
        EL
        ENL
        DOFs
        DOCs
        NoN = 1
        NoE
        scale = 10
        NodeSwitch = "On"
        colorMap = jet
        sortedNew
        sortedExisting
        SelectedNode
        Direction
        BC
        Magnitude
        VectorScale = 0.5
    end
    
    methods (Access = public)
        
        function results = updateAxes(app)
        cla(app.UIAxes);
        colormap(app.UIAxes, app.colorMap);
        colorbar(app.UIAxes);
        hold(app.UIAxes, "on");
    
        % 1. Plot Elements
        for i = 1 : app.NoE
            H = plot(app.UIAxes, [app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)], ...
                     [app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)], ...
                     "LineWidth", 3, "Color", "w");
            H.Color(4) = 0.2; % transparency
        end
    
        % 2. Plot Nodes
        for i = 1 : app.NoN
            plot(app.UIAxes, app.NL(i,1), app.NL(i,2), 'o', ...
                 'MarkerSize', app.scale, 'MarkerFaceColor', 'r');
        end
    
        % 3. Plot Boundary Conditions (Normalized Vectors)
        if ~isempty(app.ENL)
            for i = 1:app.NoN
                x = app.ENL(i,1);
                y = app.ENL(i,2);

                % --- Plot Forces (Green) ---
                fx = app.ENL(i,11);
                fy = app.ENL(i,12);
                if fx ~= 0 || fy ~= 0
                    mag = sqrt(fx^2 + fy^2);
                    % Normalize direction and apply fixed app.VectorScale
                    ux_dir = (fx/mag) * app.VectorScale;
                    uy_dir = (fy/mag) * app.VectorScale;
                    
                    quiver(app.UIAxes, x, y, ux_dir, uy_dir, 0, 'Color', [0 1 0], ...
                        'LineWidth', 2, 'MaxHeadSize', 1);
                    text(app.UIAxes, x + ux_dir, y + uy_dir, " " + mag + " N", 'Color', [0 1 0]);
                end

                % --- Plot Displacements (Cyan) ---
                ux = app.ENL(i,9);
                uy = app.ENL(i,10);
                if (app.ENL(i,3) == -1 || app.ENL(i,4) == -1) && (ux ~= 0 || uy ~= 0)
                    mag_u = sqrt(ux^2 + uy^2);
                    % Use a slightly smaller scale for displacements to distinguish them
                    ux_disp = (ux/mag_u) * (app.VectorScale * 0.7);
                    uy_disp = (uy/mag_u) * (app.VectorScale * 0.7);
                    
                    quiver(app.UIAxes, x, y, ux_disp, uy_disp, 0, 'Color', [0 1 1], ...
                        'LineWidth', 2, 'LineStyle', '-.');
                end
            end
        end
    
        % 4. Node Labels
        if strcmp(app.NodeSwitch, "On")
            for i = 1:app.NoN
                text(app.UIAxes, app.NL(i,1), app.NL(i,2), num2str(i), ...
                    "Color", "w", "FontSize", 12, "HorizontalAlignment", "center")
            end
        end
        end

        function applyGradient(~, targetButton, startCol, endCol)
            % Create a smooth 100-pixel gradient
            cols = 100; rows = 20;
            
            % Linear interpolation for R, G, and B channels
            r = linspace(startCol(1), endCol(1), cols);
            g = linspace(startCol(2), endCol(2), cols);
            b = linspace(startCol(3), endCol(3), cols);
            
            % Combine into an RGB image matrix
            gradRow = reshape([r; g; b]', [1, cols, 3]);
            iconMatrix = repmat(gradRow, [rows, 1, 1]);
            
            % Assign to button
            targetButton.Icon = iconMatrix;

            % Center
            targetButton.IconAlignment = 'center';
        end
    end
    
    methods (Access = private)
        function [ENL, DOFs, DOCs] = update_Global_Indices(app)
            % This implements the re-indexing logic from assign_BCs.m
            ENL = app.ENL;
            NoN = app.NoN;
            PD = 2; % 2D Problem
            
            DOFs = 0;
            DOCs = 0;
            
            % Assign relative indices
            for i = 1:NoN
                for j = 1:PD
                    if (ENL(i,PD+j) == -1)
                        DOCs = DOCs - 1;
                        ENL(i,2*PD+j) = DOCs;
                    else
                        DOFs = DOFs + 1;
                        ENL(i,2*PD+j) = DOFs;
                    end
                end
            end
            
            % Assign global matrix positions
            for i = 1:NoN
                for j = 1:PD
                    if(ENL(i,(2*PD+j)) < 0)
                        ENL(i,PD*3+j) = DOFs + abs(ENL(i,(2*PD+j)));
                    else
                        ENL(i,PD*3+j) = ENL(i,(2*PD+j));
                    end
                end
            end
            DOCs = abs(DOCs);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Button gradients
            app.applyGradient(app.GenerateNodeButton, [0.2 0 0.2], [1 0 1])
            app.applyGradient(app.GenerateElementButton, [0 0 0.4], [0 0 1]);
            app.applyGradient(app.ImportfromExcelButton, [0.6 0.6 0.6], [0 0.5 0]);
            app.applyGradient(app.ApplyButton, [0.2 0.2 0.2], [0.0 0.6 0.2]);
            app.applyGradient(app.RemoveButton, [0.3, 0, 0], [1, 0, 0]);
            app.applyGradient(app.ResetButton, [0.2 0.1 0], [0.8 0.5 0]);
    
        end

        % Button pushed function: GenerateNodeButton
        function GenerateNodeButtonPushed(app, event)
            nl = [app.X_coord.Value, app.Y_coord.Value];
            
            if ~isempty(app.NL) && ismember(nl, app.NL, "rows")
                uialert(app.UIFigure, "Node already exists!", "Duplicate Entry")
                return
            end
            
            % Update Node List
            app.NL = [app.NL; nl];
            app.NL_Table.Data = app.NL;
            app.NoN = size(app.NL, 1);
            
            % --- THE FIX: Sync ENL with the new node ---
            if ~isempty(app.ENL)
                % Add a new blank row of zeros to ENL (PD*6 = 12 columns)
                newRow = zeros(1, 12); 
                newRow(1:2) = nl; % Set the coordinates in the first two columns
                app.ENL = [app.ENL; newRow];
                
                % Re-calculate global indices so the new node has a valid DOF
                [app.ENL, app.DOFs, app.DOCs] = app.update_Global_Indices();
            end
            
            app.updateAxes(); % Now this won't crash
            
        end

        % Button pushed function: GenerateElementButton
        function GenerateElementButtonPushed(app, event)

           el = [app.Node1.Value, app.Node2.Value];
            
            % 1. Validation
            if app.NoN < 2
                uialert(app.UIFigure, "At least 2 nodes are needed to generate an element!", "Input Error"); return
            elseif app.Node1.Value == app.Node2.Value
                uialert(app.UIFigure, "Nodes must be different!", "Input Error"); return
            elseif app.Node1.Value > app.NoN || app.Node2.Value > app.NoN
                uialert(app.UIFigure, "Node range out of index!", "Input Error"); return
            end
            
            % 2. Duplicate Check
            if ~isempty(app.EL)
                sortedNew = sort(el);
                sortedExisting = sort(app.EL, 2);
                if ismember(sortedNew, sortedExisting, "rows")
                    uialert(app.UIFigure, "Element already exists!", "Duplicate Entry");
                    return
                end
            end
            
            % 3. Add the element
            app.EL = [app.EL; el];
            app.EL_Table.Data = app.EL;
            app.NoE = size(app.EL, 1);
            
            % 4. Refresh
            app.updateAxes();

        end

        % Callback function
        function ScaleSliderValueChanged(app, event)


        end

        % Value changed function: ScaleSlider
        function ScaleSliderValueChanged2(app, event)
            app.scale = app.ScaleSlider.Value;
            
            updateAxes(app);
            
        end

        % Value changed function: NodeLabelSwitch
        function NodeLabelSwitchValueChanged(app, event)
            app.NodeSwitch = app.NodeLabelSwitch.Value;
            
            updateAxes(app);

        end

        % Selection changed function: StyleButtonGroup
        function StyleButtonGroupSelectionChanged(app, event)
            SelectedButton = app.StyleButtonGroup.SelectedObject;
            
            switch SelectedButton

                case app.Jet

                    app.colorMap = jet;
                    colormap(app.UIAxes, app.colorMap);

                case app.Parula

                    app.colorMap = parula;
                    colormap(app.UIAxes, app.colorMap);

                case app.Bone

                    app.colorMap = bone;
                    colormap(app.UIAxes, app.colorMap);

                case app.Winter

                    app.colorMap = winter;
                    colormap(app.UIAxes, app.colorMap);

                case app.Cool

                    app.colorMap = cool;
                    colormap(app.UIAxes, app.colorMap);

                    
            end
        end

        % Button pushed function: ImportfromExcelButton
        function ImportfromExcelButtonPushed(app, event)
            [inputfile, pathname] = uigetfile({"*.xlsx";"*.slx"}, "Select file to open");
            
                if ~isequal(inputfile, 0)
                    fullPath = fullfile(pathname, inputfile);
                    
                    nl_raw = readmatrix(fullPath, "Range", "A:B");
                    el_raw = readmatrix(fullPath, "Range", "C:D");
                    
                    app.NL = nl_raw(~any(isnan(nl_raw), 2), :);
                    app.EL = el_raw(~any(isnan(el_raw), 2), :);
                    
                    app.NoN = size(app.NL, 1);
                    app.NoE = size(app.EL, 1);
                    app.NL_Table.Data = app.NL;
                    app.EL_Table.Data = app.EL;
                    
                    % 1. Sync Spinner
                    if app.NoN > 0
                        app.NodeSpinner.Limits = [1, app.NoN];
                        app.NodeSpinner.Value = 1;
                    end
            
                    % 2. Initialize BCs (This creates the blank ENL)
                    [app.ENL, app.DOFs, app.DOCs] = assign_BCs(app.NL);
                    [app.ENL, app.DOFs, app.DOCs] = app.update_Global_Indices();
            
                    % 3. CALCULATE VECTOR SCALE (After NL is populated)
                    if ~isempty(app.NL)
                        modelWidth = max(app.NL(:,1)) - min(app.NL(:,1));
                        modelHeight = max(app.NL(:,2)) - min(app.NL(:,2));
                        % Set scale to 10% of the largest dimension
                        app.VectorScale = max([modelWidth, modelHeight, 1]) * 0.1; 
                    end
                    
                    app.updateAxes();
                end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Value changed function: NodeSpinner
        function NodeSpinnerValueChanged(app, event)
            app.SelectedNode = app.NodeSpinner.Value;
            app.NodeSpinner.Limits = [1, app.NoN];
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % 1. Capture current UI state
            nodeIdx   = app.NodeSpinner.Value;
            direction = app.AxisSwitch.Value;
            bcType    = app.BoundaryConditionSwitch.Value; % '0' for Force, '1' for Disp
            magnitude = app.MagnitudeEditField.Value;
        
            % 2. Initialize ENL if empty
            if isempty(app.ENL) || size(app.ENL, 1) ~= app.NoN
                [app.ENL, app.DOFs, app.DOCs] = assign_BCs(app.NL);
            end
        
            % 3. Determine Column Indices
            if strcmp(direction, 'X')
                typeCol = 3; valColDisp = 9; valColForce = 11;
            else
                typeCol = 4; valColDisp = 10; valColForce = 12;
            end
        
            % 4. Check for existing NON-ZERO BC to trigger UI Prompt
            existingVal = 0;
            if app.ENL(nodeIdx, typeCol) == 1
                existingVal = app.ENL(nodeIdx, valColForce);
            elseif app.ENL(nodeIdx, typeCol) == -1
                existingVal = app.ENL(nodeIdx, valColDisp);
            end
        
            % Only ask for confirmation if the value is actually something other than zero
            if existingVal ~= 0
                msg = sprintf('Node %d (%s) already has a value of %g. Overwrite?', ...
                      nodeIdx, direction, existingVal);
                selection = uiconfirm(app.UIFigure, msg, 'Confirm Overwrite', ...
                            'Options', {'Overwrite', 'Cancel'}, 'DefaultOption', 1);
                
                if strcmp(selection, 'Cancel')
                    return; 
                end
            end
        
            % 5. Apply the logic (Overwriting)
            if strcmp(bcType, '1') % Displacement
                app.ENL(nodeIdx, typeCol) = -1;
                app.ENL(nodeIdx, valColDisp) = magnitude;
                app.ENL(nodeIdx, valColForce) = 0; 
            else % Force
                app.ENL(nodeIdx, typeCol) = 1;
                app.ENL(nodeIdx, valColForce) = magnitude;
                app.ENL(nodeIdx, valColDisp) = 0; 
            end
        
            % 6. Update Indices and Axes
            [app.ENL, app.DOFs, app.DOCs] = app.update_Global_Indices();
            app.updateAxes();

        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            % 1. Check if ENL exists
            if isempty(app.ENL)
                return; 
            end
        
            % 2. Capture current target from UI
            nodeIdx   = app.NodeSpinner.Value;
            direction = app.AxisSwitch.Value;
        
            % 3. Determine Column Indices
            if strcmp(direction, 'X')
                typeCol = 3; valColDisp = 9; valColForce = 11;
            else
                typeCol = 4; valColDisp = 10; valColForce = 12;
            end
        
            % 4. Zero out the data for this specific node/axis
            app.ENL(nodeIdx, typeCol) = 0;      % Set type to 0 (No BC)
            app.ENL(nodeIdx, valColDisp) = 0;   % Clear Displacement
            app.ENL(nodeIdx, valColForce) = 0;  % Clear Force
        
            % 5. Re-calculate Global Indices
            % Necessary because removing a constraint changes the DOF count
            [app.ENL, app.DOFs, app.DOCs] = app.update_Global_Indices();
        
            % 6. Refresh UI
            app.updateAxes();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            selection = uiconfirm(app.UIFigure, 'This will delete all nodes, elements, and boundary conditions. Continue?', ...
                'Confirm Reset', 'Icon', 'warning');
            
            if strcmp(selection, 'OK')
                % Clear Data
                app.NL = [];
                app.EL = [];
                app.ENL = [];
                app.NoN = 0;
                app.NoE = 0;
                
                % Clear Tables
                app.NL_Table.Data = [];
                app.EL_Table.Data = [];
                
                % Reset Spinner
                app.NodeSpinner.Limits = [1, 1];
                app.NodeSpinner.Value = 1;
                
                % Refresh Plot
                app.updateAxes();
            end
        end

        % Value changed function: MagnitudeEditField
        function MagnitudeEditFieldValueChanged(app, event)
            app.Magnitude = app.MagnitudeEditField.Value;
            
        end

        % Value changed function: BoundaryConditionSwitch
        function BoundaryConditionSwitchValueChanged(app, event)
            app.BC = app.BoundaryConditionSwitch.Value;

        end

        % Value changed function: AxisSwitch
        function AxisSwitchValueChanged(app, event)
            app.Direction = app.AxisSwitch.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Pre-Processing')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.StressAxes = uiaxes(app.GridLayout);
            title(app.StressAxes, 'Stress')
            xlabel(app.StressAxes, 'X')
            ylabel(app.StressAxes, 'Y')
            zlabel(app.StressAxes, 'Z')
            app.DisplacementAxes = uiaxes(app.GridLayout);
            title(app.DisplacementAxes, 'Displacement')
            xlabel(app.DisplacementAxes, 'X')
            ylabel(app.DisplacementAxes, 'Y')
            zlabel(app.DisplacementAxes, 'Z')
            app.XLabel = uilabel(app.GridLayout);
            app.XLabel.Text = 'X';
            app.X_coord = uieditfield(app.GridLayout, 'numeric');
            app.YLabel = uilabel(app.GridLayout);
            app.YLabel.Text = 'Y';
            app.Y_coord = uieditfield(app.GridLayout, 'numeric');
            app.NodeLabel = uilabel(app.GridLayout);
            app.NodeLabel.Text = 'Node';
            app.GenerateNodeButton = uibutton(app.GridLayout, 'push');
            app.GenerateNodeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNodeButtonPushed, true);
            app.GenerateNodeButton.Text = {'Generate'; 'Node'};
            app.NL_Table = uitable(app.GridLayout);
            app.NL_Table.ColumnName = {'X'; 'Y'};
            app.NL_Table.RowName = {};
            app.NodeListLabel = uilabel(app.GridLayout);
            app.NodeListLabel.Text = 'Node List';
            app.Node1EditFieldLabel = uilabel(app.GridLayout);
            app.Node1EditFieldLabel.Text = 'Node 1';
            app.Node1 = uieditfield(app.GridLayout, 'numeric');
            app.Node1.Value = 1;
            app.Node2EditFieldLabel = uilabel(app.GridLayout);
            app.Node2EditFieldLabel.Text = 'Node 2';
            app.Node2 = uieditfield(app.GridLayout, 'numeric');
            app.Node2.Value = 2;
            app.ElementLabel = uilabel(app.GridLayout);
            app.ElementLabel.Text = 'Element';
            app.GenerateElementButton = uibutton(app.GridLayout, 'push');
            app.GenerateElementButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateElementButtonPushed, true);
            app.GenerateElementButton.Text = {'Generate'; 'Element'};
            app.EL_Table = uitable(app.GridLayout);
            app.EL_Table.ColumnName = {'Node 1'; 'Node 2'};
            app.EL_Table.RowName = {};
            app.ElementListLabel = uilabel(app.GridLayout);
            app.ElementListLabel.Text = 'Element List';
            app.NodeLabelSwitchLabel = uilabel(app.GridLayout);
            app.NodeLabelSwitchLabel.Text = 'Node Label';
            app.NodeLabelSwitch = uiswitch(app.GridLayout, 'slider');
            app.NodeLabelSwitch.ValueChangedFcn = createCallbackFcn(app, @NodeLabelSwitchValueChanged, true);
            app.NodeLabelSwitch.Value = 'On';
            app.ImportfromExcelButton = uibutton(app.GridLayout, 'push');
            app.ImportfromExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ImportfromExcelButtonPushed, true);
            app.ImportfromExcelButton.Text = 'Import from Excel';
            app.StyleButtonGroup = uibuttongroup(app.GridLayout);
            app.StyleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StyleButtonGroupSelectionChanged, true);
            app.StyleButtonGroup.Title = 'Style';
            app.Jet = uitogglebutton(app.StyleButtonGroup);
            app.Jet.Icon = fullfile(pathToMLAPP, 'colormap_jet.png');
            app.Jet.Text = '';
            app.Jet.Value = true;
            app.Parula = uitogglebutton(app.StyleButtonGroup);
            app.Parula.Icon = fullfile(pathToMLAPP, 'colormap_parula_update17a.png');
            app.Parula.Text = '';
            app.Bone = uitogglebutton(app.StyleButtonGroup);
            app.Bone.Icon = fullfile(pathToMLAPP, 'colormap_bone.png');
            app.Bone.Text = '';
            app.Winter = uitogglebutton(app.StyleButtonGroup);
            app.Winter.Icon = fullfile(pathToMLAPP, 'colormap_winter.png');
            app.Winter.Text = '';
            app.Cool = uitogglebutton(app.StyleButtonGroup);
            app.Cool.Icon = fullfile(pathToMLAPP, 'colormap_cool.png');
            app.Cool.Text = '';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.NodeSpinnerLabel = uilabel(app.GridLayout2);
            app.NodeSpinnerLabel.Text = 'Node';
            app.NodeSpinner = uispinner(app.GridLayout2);
            app.NodeSpinner.ValueChangedFcn = createCallbackFcn(app, @NodeSpinnerValueChanged, true);
            app.NodeSpinner.Value = 1;
            app.BoundaryConditionLabel = uilabel(app.GridLayout2);
            app.BoundaryConditionLabel.Text = {'Boundary'; 'Condition'};
            app.BoundaryConditionSwitch = uiswitch(app.GridLayout2, 'toggle');
            app.BoundaryConditionSwitch.Items = {'Force', 'Displacement'};
            app.BoundaryConditionSwitch.ItemsData = {'0', '1'};
            app.BoundaryConditionSwitch.ValueChangedFcn = createCallbackFcn(app, @BoundaryConditionSwitchValueChanged, true);
            app.BoundaryConditionSwitch.Value = '0';
            app.AxisSwitchLabel = uilabel(app.GridLayout2);
            app.AxisSwitchLabel.Text = 'Axis';
            app.AxisSwitch = uiswitch(app.GridLayout2, 'slider');
            app.AxisSwitch.Items = {'X', 'Y'};
            app.AxisSwitch.ValueChangedFcn = createCallbackFcn(app, @AxisSwitchValueChanged, true);
            app.AxisSwitch.Value = 'X';
            app.MagnitudeEditFieldLabel = uilabel(app.GridLayout2);
            app.MagnitudeEditFieldLabel.Text = 'Magnitude';
            app.MagnitudeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MagnitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MagnitudeEditFieldValueChanged, true);
            app.ApplyButton = uibutton(app.GridLayout2, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RemoveButton = uibutton(app.GridLayout2, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.ScaleSliderLabel = uilabel(app.GridLayout);
            app.ScaleSliderLabel.Text = 'Scale';
            app.ScaleSlider = uislider(app.GridLayout);
            app.ScaleSlider.Limits = [1 100];
            app.ScaleSlider.ValueChangedFcn = createCallbackFcn(app, @ScaleSliderValueChanged2, true);
            app.ScaleSlider.Value = 10;
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.MaterialPropertiesLabel = uilabel(app.GridLayout3);
            app.MaterialPropertiesLabel.Text = 'Material Properties';
            app.YoungsModulusEEditFieldLabel = uilabel(app.GridLayout3);
            app.YoungsModulusEEditFieldLabel.Text = 'Young''s Modulus (E)';
            app.YoungsModulusEEditField = uieditfield(app.GridLayout3, 'numeric');
            app.CrossSectionalAreaAEditFieldLabel = uilabel(app.GridLayout3);
            app.CrossSectionalAreaAEditFieldLabel.Text = 'Cross Sectional Area (A)';
            app.CrossSectionalAreaAEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MaterialDropDownLabel = uilabel(app.GridLayout3);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.GridLayout3);
            app.MaterialDropDown.Items = {'Custom', 'X', 'Import New...'};
            app.MaterialDropDown.Value = 'Custom';
            app.SafetyFactorCheckBox = uicheckbox(app.GridLayout3);
            app.SafetyFactorCheckBox.Text = 'Safety Factor';
            app.EditField = uieditfield(app.GridLayout3, 'numeric');
            app.EditField.Editable = 'off';
            app.YieldStrengthEditFieldLabel = uilabel(app.GridLayout3);
            app.YieldStrengthEditFieldLabel.Text = 'Yield Strength';
            app.YieldStrengthEditField = uieditfield(app.GridLayout3, 'numeric');
            app.YieldStrengthEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FEM_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end